classdef FEMLabApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FEMLabUIFigure              matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewMenu                     matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SavesettingsMenu            matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        MesherTab                   matlab.ui.container.Tab
        CurrentMeshPanel            matlab.ui.container.Panel
        MeshreadyLampLabel          matlab.ui.control.Label
        MeshreadyLamp               matlab.ui.control.Lamp
        TableSetRegions             matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
        MesherPanel                 matlab.ui.container.Panel
        ElementtypeButtonGroup      matlab.ui.container.ButtonGroup
        Triangle1stOrderButton      matlab.ui.control.RadioButton
        Triangle2ndOrderButton      matlab.ui.control.RadioButton
        Triangle3rdOrderButton      matlab.ui.control.RadioButton
        ElemTypeAxes                matlab.ui.control.UIAxes
        RunsetupButton              matlab.ui.control.Button
        MeshrefinementPanel         matlab.ui.container.Panel
        FactorSpinnerLabel          matlab.ui.control.Label
        FactorSpinner               matlab.ui.control.Spinner
        RegionsPanel                matlab.ui.container.Panel
        TableEditRegions            matlab.ui.control.Table
        EditmeshButton              matlab.ui.control.Button
        UpdateparamsButton          matlab.ui.control.Button
        PreprocessorTab             matlab.ui.container.Tab
        PreprocPanel                matlab.ui.container.Panel
        ProblemtypeButtonGroup      matlab.ui.container.ButtonGroup
        ElectrostaticButton         matlab.ui.control.RadioButton
        StaticcurrentButton         matlab.ui.control.RadioButton
        MagnetostaticButton         matlab.ui.control.RadioButton
        SymmetryButtonGroup         matlab.ui.container.ButtonGroup
        PlanesymmetricButton        matlab.ui.control.RadioButton
        AxissymmetricButton         matlab.ui.control.RadioButton
        CalculationtypeButtonGroup  matlab.ui.container.ButtonGroup
        IntegrationButton           matlab.ui.control.RadioButton
        ExactformulaButton          matlab.ui.control.RadioButton
        CleanprojectButton          matlab.ui.control.Button
        ProcessButton               matlab.ui.control.Button
        StatePanel                  matlab.ui.container.Panel
        PreprocessingLampLabel      matlab.ui.control.Label
        PreprocessingLamp           matlab.ui.control.Lamp
        RegionparametersPanel       matlab.ui.container.Panel
        UITable                     matlab.ui.control.Table
        EdgetypeButtonGroup         matlab.ui.container.ButtonGroup
        RectangularButton           matlab.ui.control.RadioButton
        CircularButton              matlab.ui.control.RadioButton
        ComputationPanel            matlab.ui.container.Panel
        FieldPanel                  matlab.ui.container.Panel
        ComputeEButton              matlab.ui.control.Button
        ComputeJButton              matlab.ui.control.Button
        ComputeBButton              matlab.ui.control.Button
        ComputeAButton              matlab.ui.control.Button
        EcomputedLabel              matlab.ui.control.Label
        EcomputedLamp               matlab.ui.control.Lamp
        JcomputedLampLabel          matlab.ui.control.Label
        JcomputedLamp               matlab.ui.control.Lamp
        BcomputedLampLabel          matlab.ui.control.Label
        BcomputedLamp               matlab.ui.control.Lamp
        AcomputedLampLabel          matlab.ui.control.Label
        AcomputedLamp               matlab.ui.control.Lamp
        IntegralsPanel              matlab.ui.container.Panel
        PostprocessorTab            matlab.ui.container.Tab
        PostprocPanel               matlab.ui.container.Panel
        ButtonGroup                 matlab.ui.container.ButtonGroup
        Button                      matlab.ui.control.RadioButton
        Button2                     matlab.ui.control.RadioButton
        Button3                     matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        projPath = pwd; % Path to project
        projName = 'Default.proj'; % Initial project name
        
    end
    
    properties (Access = private)
        
       projectSelected = 1;
       
       files;
       
       settings = struct('problemClass', 'Electrostatic',... 
                         'symmetry', 'Plane symmetric',...
                         'computation', 'Integration', ...
                         'edgeType', 'Rectangular');
       
    end
    
    methods (Access = private)
        
        % Changes the visibility of the tools if no proj selected
        function changeProjectState(app, select)
            app.projectSelected = select;
            if(~app.projectSelected)
                app.TabGroup.Visible = 'off';
            else
                app.TabGroup.Visible = 'on';
            end
        end
        
        function state = checkButtonState(app, button)
            value = button.Text;
            switch value
                case 'Electrostatic'
                    state = app.STATIC_E;
                case 'Static current'
                    state = app.STATIC_C;
                case 'Magnetostatic'
                    state = app.STATIC_M;
                case 'Plane symmetric'
                    state = app.PLANE_SYM;
                case 'Axis symmetric'
                    state = app.AXIS_SYM;
                case 'Integration'
                    state = app.COMP_INT;
                case 'Exact formula'
                    state = app.COMP_FORM;
                case 'Rectangular'
                    state = app.RECT;
                case 'Circular'
                    state = app.CIRC;
            end
        end
       
        function updateProblemSettings(app)
            
            settings = app.settings;
            save(app.files.setfile, 'settings')
            
        end
    
        function changePreprocState(app, state)
            if(strcmp(state, 'busy'))
                app.PreprocessingLamp.Color = [1,1,0];    
            elseif(strcmp(state, 'ready'))
                app.PreprocessingLamp.Color = [0,1,0];
            elseif(strcmp(state, 'init'))
                app.PreprocessingLamp.Color = [1,0,0];
            end
        end
    
        function changeMesherState(app, state)
            if(strcmp(state, 'busy'))
                app.MeshreadyLamp.Color = [1,1,0];    
            elseif(strcmp(state, 'ready'))
                app.MeshreadyLamp.Color = [0,1,0];
            elseif(strcmp(state, 'init'))
                app.MeshreadyLamp.Color = [1,0,0];
            end
        end
    
        function startPreprocessor(app)
            
            app.changePreprocState('busy')
            msh.prepFieldData(app.files, app.settings)
            slv.calcA(app.files, app.settings)
            slv.evalA(app.files)
            app.changePreprocState('ready')
            
        end
    
        function cleanProjectFolder(app)
            [status, message, messageid] = rmdir(app.files.pltpth, 's');
            [status, message, messageid] = rmdir(app.files.results, 's');
            delete(app.files.setfile);
            app.initComponents()
        end
    
        function changeComputeLampState(app, lamp, label, state)
            if(strcmp(state, 'busy'))
                lamp.Color = [1,1,0];
                label.Text = 'Computing...';
            elseif(strcmp(state, 'ready'))
                lamp.Color = [0,1,0];
                label.Text = 'Computed';
            elseif(strcmp(state, 'init'))
                lamp.Color = [1,0,0];
                label.Text = 'Not computed';
            end
        end
    end
    
    methods (Access = public)
        
        function initComponents(app)
            
            addpath('functions')
            
            % Project selected state : 
            app.changeProjectState(0)
            
            % ElemTypeAxes init :
            title(app.ElemTypeAxes, '')
            ylabel(app.ElemTypeAxes, '')
            xlabel(app.ElemTypeAxes, '')
            app.setElementTypeImg(1)
            
            % Project name init
            app.setProjectTitle()
            
            %% Lamps intializer :
            % MeshreadyLamp
            app.MeshreadyLamp.Color = [1,0,0];
            
            % Preprocessed lamp :
            app.PreprocessingLamp.Color = [1,0,0];
            
            % Computation lamps : 
            app.changeComputeLampState(app.EcomputedLamp, app.EcomputedLabel, 'init')
            app.changeComputeLampState(app.BcomputedLamp, app.BcomputedLampLabel, 'init')
            app.changeComputeLampState(app.JcomputedLamp, app.JcomputedLampLabel, 'init')
            app.changeComputeLampState(app.AcomputedLamp, app.AcomputedLampLabel, 'init')
            
        end
        
        % Sets the element type image
        function setElementTypeImg(app, i)
            img = imread(['./AppFiles/figures/', num2str(i), 'o_triangle.png']);
            imshow(img, 'parent', app.ElemTypeAxes)
        end
        
        % Sets project title 
        function setProjectTitle(app)
            app.PreprocPanel.Title = app.projName;
            app.MesherPanel.Title = app.projName;
            app.PostprocPanel.Title = app.projName;
        end
        
        
    end
    

    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
       
            app.projPath = uigetdir(pwd, 'Select project folder');
            pathParts = strsplit(app.projPath, '\');
            app.projName = pathParts{end};
            app.initComponents()
            app.changeProjectState(1)
            app.files = io.parseGmesh(app.projPath, app.projName);
            app.setProjectTitle()
            
            
        end

        % Selection changed function: ProblemtypeButtonGroup
        function ProblemtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProblemtypeButtonGroup.SelectedObject;
            app.settings.problemClass = selectedButton.Text;
            app.updateProblemSettings();
        end

        % Selection changed function: SymmetryButtonGroup
        function SymmetryButtonGroupSelectionChanged(app, event)
            selectedButton = app.SymmetryButtonGroup.SelectedObject;
            app.settings.symmetry = selectedButton.Text;
            app.updateProblemSettings();
        end

        % Selection changed function: CalculationtypeButtonGroup
        function CalculationtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalculationtypeButtonGroup.SelectedObject;
            app.settings.computation = selectedButton.Text;
            app.updateProblemSettings();
        end

        % Selection changed function: EdgetypeButtonGroup
        function EdgetypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.EdgetypeButtonGroup.SelectedObject;
            app.settings.edgeType = selectedButton.Text;
            app.updateProblemSettings();
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.startPreprocessor()
        end

        % Button pushed function: CleanprojectButton
        function CleanprojectButtonPushed(app, event)
            app.cleanProjectFolder()
        end

        % Button pushed function: ComputeEButton
        function ComputeEButtonPushed(app, event)
            app.changeComputeLampState(app.EcomputedLamp, app.EcomputedLabel, 'busy')
            slv.calcE(app.files, app.settings)
            app.changeComputeLampState(app.EcomputedLamp, app.EcomputedLabel, 'ready')
        end

        % Button pushed function: ComputeJButton
        function ComputeJButtonPushed(app, event)
            app.changeComputeLampState(app.JcomputedLamp, app.JcomputedLampLabel, 'busy')
            slv.calcJ(app.files, app.settings)
            app.changeComputeLampState(app.JcomputedLamp, app.EcomputedLampLabel, 'ready')
        end

        % Button pushed function: ComputeBButton
        function ComputeBButtonPushed(app, event)
            app.changeComputeLampState(app.BcomputedLamp, app.BcomputedLampLabel, 'busy')
            slv.calcB(app.files, app.settings)
            app.changeComputeLampState(app.BcomputedLamp, app.BcomputedLampLabel, 'ready')
        end

        % Button pushed function: ComputeAButton
        function ComputeAButtonPushed(app, event)
            app.changeComputeLampState(app.AcomputedLamp, app.AcomputedLampLabel, 'busy')
            slv.evalA(app.files)
            app.changeComputeLampState(app.AcomputedLamp, app.AcomputedLampLabel, 'ready')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FEMLabUIFigure = uifigure;
            app.FEMLabUIFigure.Name = 'FEMLab';
            app.FileMenu = uimenu(app.FEMLabUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.SavesettingsMenu = uimenu(app.FileMenu);
            app.SavesettingsMenu.Accelerator = 'S';
            app.SavesettingsMenu.Text = 'Save settings';
            app.HelpMenu = uimenu(app.FEMLabUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.FEMLabUIFigure);
            app.MesherTab = uitab(app.TabGroup);
            app.MesherTab.Title = 'Mesher';
            app.CurrentMeshPanel = uipanel(app.MesherTab);
            app.CurrentMeshPanel.Title = 'Current Mesh ';
            app.MeshreadyLampLabel = uilabel(app.CurrentMeshPanel);
            app.MeshreadyLampLabel.Text = 'Mesh ready';
            app.MeshreadyLamp = uilamp(app.CurrentMeshPanel);
            app.TableSetRegions = uitable(app.CurrentMeshPanel);
            app.TableSetRegions.ColumnName = {'# Nodes'; '# Elements'; 'Element type'};
            app.TableSetRegions.RowName = {};
            app.UIAxes = uiaxes(app.CurrentMeshPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MesherPanel = uipanel(app.MesherTab);
            app.MesherPanel.Title = 'Default.proj';
            app.ElementtypeButtonGroup = uibuttongroup(app.MesherPanel);
            app.ElementtypeButtonGroup.Title = 'Element type :';
            app.Triangle1stOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle1stOrderButton.Text = 'Triangle 1st Order';
            app.Triangle1stOrderButton.Value = true;
            app.Triangle2ndOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle2ndOrderButton.Text = 'Triangle 2nd Order';
            app.Triangle3rdOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle3rdOrderButton.Text = 'Triangle 3rd Order';
            app.ElemTypeAxes = uiaxes(app.ElementtypeButtonGroup);
            title(app.ElemTypeAxes, 'Title')
            xlabel(app.ElemTypeAxes, 'X')
            ylabel(app.ElemTypeAxes, 'Y')
            app.ElemTypeAxes.XTick = [];
            app.ElemTypeAxes.YTick = [];
            app.RunsetupButton = uibutton(app.MesherPanel, 'push');
            app.RunsetupButton.Text = 'Run setup';
            app.MeshrefinementPanel = uipanel(app.MesherPanel);
            app.MeshrefinementPanel.Title = 'Mesh refinement : ';
            app.FactorSpinnerLabel = uilabel(app.MeshrefinementPanel);
            app.FactorSpinnerLabel.Text = 'Factor : ';
            app.FactorSpinner = uispinner(app.MeshrefinementPanel);
            app.FactorSpinner.Value = 1;
            app.RegionsPanel = uipanel(app.MesherPanel);
            app.RegionsPanel.Title = 'Regions : ';
            app.TableEditRegions = uitable(app.RegionsPanel);
            app.TableEditRegions.ColumnName = {'ID'; 'Name'; 'Type'};
            app.TableEditRegions.RowName = {};
            app.TableEditRegions.ColumnEditable = [true false false];
            app.EditmeshButton = uibutton(app.MesherPanel, 'push');
            app.EditmeshButton.Text = 'Edit mesh';
            app.UpdateparamsButton = uibutton(app.MesherPanel, 'push');
            app.UpdateparamsButton.Text = 'Update params';
            app.PreprocessorTab = uitab(app.TabGroup);
            app.PreprocessorTab.Title = 'Preprocessor';
            app.PreprocPanel = uipanel(app.PreprocessorTab);
            app.PreprocPanel.Title = 'Default.proj';
            app.ProblemtypeButtonGroup = uibuttongroup(app.PreprocPanel);
            app.ProblemtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProblemtypeButtonGroupSelectionChanged, true);
            app.ProblemtypeButtonGroup.Title = 'Problem type : ';
            app.ElectrostaticButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.ElectrostaticButton.Text = 'Electrostatic';
            app.ElectrostaticButton.Value = true;
            app.StaticcurrentButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.StaticcurrentButton.Text = 'Static current';
            app.MagnetostaticButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.MagnetostaticButton.Text = 'Magnetostatic';
            app.SymmetryButtonGroup = uibuttongroup(app.PreprocPanel);
            app.SymmetryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SymmetryButtonGroupSelectionChanged, true);
            app.SymmetryButtonGroup.Title = 'Symmetry :';
            app.PlanesymmetricButton = uiradiobutton(app.SymmetryButtonGroup);
            app.PlanesymmetricButton.Text = 'Plane symmetric';
            app.PlanesymmetricButton.Value = true;
            app.AxissymmetricButton = uiradiobutton(app.SymmetryButtonGroup);
            app.AxissymmetricButton.Text = 'Axis symmetric';
            app.CalculationtypeButtonGroup = uibuttongroup(app.PreprocPanel);
            app.CalculationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalculationtypeButtonGroupSelectionChanged, true);
            app.CalculationtypeButtonGroup.Title = 'Calculation type : ';
            app.IntegrationButton = uiradiobutton(app.CalculationtypeButtonGroup);
            app.IntegrationButton.Text = 'Integration';
            app.IntegrationButton.Value = true;
            app.ExactformulaButton = uiradiobutton(app.CalculationtypeButtonGroup);
            app.ExactformulaButton.Text = 'Exact formula';
            app.CleanprojectButton = uibutton(app.PreprocPanel, 'push');
            app.CleanprojectButton.ButtonPushedFcn = createCallbackFcn(app, @CleanprojectButtonPushed, true);
            app.CleanprojectButton.Text = 'Clean project';
            app.ProcessButton = uibutton(app.PreprocPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.StatePanel = uipanel(app.PreprocPanel);
            app.StatePanel.Title = 'State : ';
            app.PreprocessingLampLabel = uilabel(app.StatePanel);
            app.PreprocessingLampLabel.Text = 'Preprocessing';
            app.PreprocessingLamp = uilamp(app.StatePanel);
            app.RegionparametersPanel = uipanel(app.PreprocPanel);
            app.RegionparametersPanel.Title = 'Region parameters : ';
            app.UITable = uitable(app.RegionparametersPanel);
            app.UITable.ColumnName = {'Name'; 'Parameter'};
            app.UITable.RowName = {};
            app.EdgetypeButtonGroup = uibuttongroup(app.PreprocPanel);
            app.EdgetypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EdgetypeButtonGroupSelectionChanged, true);
            app.EdgetypeButtonGroup.Title = 'Edge type : ';
            app.RectangularButton = uiradiobutton(app.EdgetypeButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.RectangularButton.Value = true;
            app.CircularButton = uiradiobutton(app.EdgetypeButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.ComputationPanel = uipanel(app.PreprocPanel);
            app.ComputationPanel.Title = 'Computation';
            app.FieldPanel = uipanel(app.ComputationPanel);
            app.FieldPanel.Title = 'Field';
            app.ComputeEButton = uibutton(app.FieldPanel, 'push');
            app.ComputeEButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeEButtonPushed, true);
            app.ComputeEButton.Text = 'Compute E';
            app.ComputeJButton = uibutton(app.FieldPanel, 'push');
            app.ComputeJButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeJButtonPushed, true);
            app.ComputeJButton.Text = 'Compute J';
            app.ComputeBButton = uibutton(app.FieldPanel, 'push');
            app.ComputeBButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeBButtonPushed, true);
            app.ComputeBButton.Text = 'Compute B';
            app.ComputeAButton = uibutton(app.FieldPanel, 'push');
            app.ComputeAButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeAButtonPushed, true);
            app.ComputeAButton.Text = 'Compute A';
            app.EcomputedLabel = uilabel(app.FieldPanel);
            app.EcomputedLabel.Text = 'E computed :';
            app.EcomputedLamp = uilamp(app.FieldPanel);
            app.JcomputedLampLabel = uilabel(app.FieldPanel);
            app.JcomputedLampLabel.Text = 'J computed :';
            app.JcomputedLamp = uilamp(app.FieldPanel);
            app.BcomputedLampLabel = uilabel(app.FieldPanel);
            app.BcomputedLampLabel.Text = 'B computed :';
            app.BcomputedLamp = uilamp(app.FieldPanel);
            app.AcomputedLampLabel = uilabel(app.FieldPanel);
            app.AcomputedLampLabel.Text = 'A computed :';
            app.AcomputedLamp = uilamp(app.FieldPanel);
            app.IntegralsPanel = uipanel(app.PreprocPanel);
            app.IntegralsPanel.Title = 'Integrals';
            app.PostprocessorTab = uitab(app.TabGroup);
            app.PostprocessorTab.Title = 'Postprocessor';
            app.PostprocPanel = uipanel(app.PostprocessorTab);
            app.PostprocPanel.Title = 'Default.proj';
            app.ButtonGroup = uibuttongroup(app.PostprocPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FEMLabApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FEMLabUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FEMLabUIFigure)
        end
    end
end